classdef DataAQ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PrestimframesEditFieldLabel   matlab.ui.control.Label
        PrestimframesEditField        matlab.ui.control.NumericEditField
        StimframesEditFieldLabel      matlab.ui.control.Label
        StimframesEditField           matlab.ui.control.NumericEditField
        Label                         matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        PoststimframesEditFieldLabel  matlab.ui.control.Label
        PoststimframesEditField       matlab.ui.control.NumericEditField
        Label_3                       matlab.ui.control.Label
        TrialdurationframesEditFieldLabel  matlab.ui.control.Label
        TrialdurationframesEditField  matlab.ui.control.NumericEditField
        StimVEditFieldLabel           matlab.ui.control.Label
        StimVEditField                matlab.ui.control.NumericEditField
        BesselFilterLOEditFieldLabel  matlab.ui.control.Label
        BesselFilterLOEditField       matlab.ui.control.NumericEditField
        StimFreqhzEditFieldLabel      matlab.ui.control.Label
        StimFreqhzEditField           matlab.ui.control.NumericEditField
        BindursEditFieldLabel         matlab.ui.control.Label
        BindursEditField              matlab.ui.control.NumericEditField
        TrialEditFieldLabel           matlab.ui.control.Label
        TrialEditField                matlab.ui.control.NumericEditField
        FrameEditFieldLabel           matlab.ui.control.Label
        FrameEditField                matlab.ui.control.NumericEditField
        StartButton                   matlab.ui.control.Button
        StopButton                    matlab.ui.control.Button
        prestim_text                  matlab.ui.control.Label
        stim_text                     matlab.ui.control.Label
        poststim_text                 matlab.ui.control.Label
        total_text                    matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        Label_6                       matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        prestim_duration_frames
        poststim_duration_frames
        stim_duration_frames
        stim_hz
        stim_voltage
        fs
        stim_signal
        nsample
        total_stim_duration
        trial_duration_frames
        bin_size
        stop
        debug
    end
    
    methods (Access = public)
        
        function update_trial_duration(app)
            app.TrialdurationframesEditField.Value = app.prestim_duration_frames+ ...
            app.poststim_duration_frames + app.stim_duration_frames;
            app.trial_duration_frames = app.TrialdurationframesEditField.Value;
            app.total_stim_duration = app.TrialdurationframesEditField.Value;
            app.update_time_calculation()
        end
        
        function update_time_calculation(app)
            app.prestim_text.Text = strcat(num2str(app.prestim_duration_frames*app.bin_size),' s');
            app.stim_text.Text = strcat(num2str(app.stim_duration_frames*app.bin_size),' s');
            app.poststim_text.Text = strcat(num2str(app.poststim_duration_frames*app.bin_size),' s');
            app.total_text.Text = strcat(num2str(app.total_stim_duration*app.bin_size),' s');
        end
        
        function disable_parameter_edit_fields(app)
            app.TrialdurationframesEditField.Editable = false;
            app.StimframesEditField.Editable = false;
            app.PrestimframesEditField.Editable = false;
            app.BindursEditField.Editable = false;
            app.StimFreqhzEditField.Editable = false;
            app.BesselFilterLOEditField.Editable = false;
            app.StimVEditField.Editable = false;            
        end
        
        function enable_parameter_edit_fields(app)
            app.TrialdurationframesEditField.Editable = true;
            app.StimframesEditField.Editable = true;
            app.PrestimframesEditField.Editable = true;
            app.BindursEditField.Editable = true;
            app.StimFreqhzEditField.Editable = true;
            app.BesselFilterLOEditField.Editable = true;
            app.StimVEditField.Editable = true;        
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.prestim_duration_frames = app.PrestimframesEditField.Value;
            app.stim_duration_frames = app.StimframesEditField.Value;
            app.poststim_duration_frames = app.PoststimframesEditField.Value;
            app.bin_size = app.BindursEditField.Value;
            app.update_trial_duration()
            app.stop = false;
            app.io = DataIO();
            app.debug = true;
        end

        % Value changed function: PrestimframesEditField
        function PrestimframesEditFieldValueChanged(app, event)
            app.prestim_duration_frames = app.PrestimframesEditField.Value;
            app.update_trial_duration();
        end

        % Value changed function: StimframesEditField
        function StimframesEditFieldValueChanged(app, event)
            app.stim_duration_frames = app.StimframesEditField.Value;
            app.update_trial_duration();
        end

        % Value changed function: PoststimframesEditField
        function PoststimframesEditFieldValueChanged(app, event)
            app.poststim_duration_frames = app.PoststimframesEditField.Value;
            app.update_trial_duration();
        end

        % Value changed function: StimVEditField
        function StimVEditFieldValueChanged(app, event)
            app.stim_voltage = app.StimVEditField.Value;
        end

        % Value changed function: StimFreqhzEditField
        function StimFreqhzEditFieldValueChanged(app, event)
            app.stim_hz = app.StimFreqhzEditField.Value;
        end

        % Value changed function: BindursEditField
        function BindursEditFieldValueChanged(app, event)
            app.bin_size = app.BindursEditField.Value;
            app.update_time_calculation()
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.TrialEditField.Value = 1;
            app.FrameEditField.Value = 1;
            app.disable_parameter_edit_fields()
            while ~(app.stop&&(app.FrameEditField.Value == app.trial_duration_frames-1))
%                 pause(app.bin_size)
%                 app.TrialEditField.Value = app.TrialEditField.Value+1;
                app.FrameEditField.Value = app.FrameEditField.Value+1;
                if app.debug
                    pause(app.bin_size)
                else
                    image = app.capturer.get_image(app.trial_duration_frames);
                end
                if app.FrameEditField.Value == app.trial_duration_frames
                    app.FrameEditField.Value = 1;
                    app.TrialEditField.Value = app.TrialEditField.Value + 1;
                end
            end
            app.stop = false;
            app.enable_parameter_edit_fields()
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stop = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PrestimframesEditFieldLabel = uilabel(app.UIFigure);
            app.PrestimframesEditFieldLabel.Text = 'Pre-stim (frames)';
            app.PrestimframesEditField = uieditfield(app.UIFigure, 'numeric');
            app.PrestimframesEditField.ValueChangedFcn = createCallbackFcn(app, @PrestimframesEditFieldValueChanged, true);
            app.PrestimframesEditField.Value = 4;
            app.StimframesEditFieldLabel = uilabel(app.UIFigure);
            app.StimframesEditFieldLabel.Text = 'Stim (frames)';
            app.StimframesEditField = uieditfield(app.UIFigure, 'numeric');
            app.StimframesEditField.ValueChangedFcn = createCallbackFcn(app, @StimframesEditFieldValueChanged, true);
            app.StimframesEditField.Value = 4;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '+';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '+';
            app.PoststimframesEditFieldLabel = uilabel(app.UIFigure);
            app.PoststimframesEditFieldLabel.Text = 'Post-stim (frames)';
            app.PoststimframesEditField = uieditfield(app.UIFigure, 'numeric');
            app.PoststimframesEditField.ValueChangedFcn = createCallbackFcn(app, @PoststimframesEditFieldValueChanged, true);
            app.PoststimframesEditField.Value = 10;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '=';
            app.TrialdurationframesEditFieldLabel = uilabel(app.UIFigure);
            app.TrialdurationframesEditFieldLabel.Text = 'Trial duration (frames)';
            app.TrialdurationframesEditField = uieditfield(app.UIFigure, 'numeric');
            app.TrialdurationframesEditField.Value = 18;
            app.StimVEditFieldLabel = uilabel(app.UIFigure);
            app.StimVEditFieldLabel.Text = 'Stim (V)';
            app.StimVEditField = uieditfield(app.UIFigure, 'numeric');
            app.StimVEditField.ValueChangedFcn = createCallbackFcn(app, @StimVEditFieldValueChanged, true);
            app.StimVEditField.Value = 1;
            app.BesselFilterLOEditFieldLabel = uilabel(app.UIFigure);
            app.BesselFilterLOEditFieldLabel.Text = 'BesselFilter LO';
            app.BesselFilterLOEditField = uieditfield(app.UIFigure, 'numeric');
            app.BesselFilterLOEditField.Value = 100;
            app.StimFreqhzEditFieldLabel = uilabel(app.UIFigure);
            app.StimFreqhzEditFieldLabel.Text = 'Stim Freq (hz)';
            app.StimFreqhzEditField = uieditfield(app.UIFigure, 'numeric');
            app.StimFreqhzEditField.ValueChangedFcn = createCallbackFcn(app, @StimFreqhzEditFieldValueChanged, true);
            app.StimFreqhzEditField.Value = 10;
            app.BindursEditFieldLabel = uilabel(app.UIFigure);
            app.BindursEditFieldLabel.Text = 'Bin dur (s)';
            app.BindursEditField = uieditfield(app.UIFigure, 'numeric');
            app.BindursEditField.ValueChangedFcn = createCallbackFcn(app, @BindursEditFieldValueChanged, true);
            app.BindursEditField.Value = 0.1;
            app.TrialEditFieldLabel = uilabel(app.UIFigure);
            app.TrialEditFieldLabel.Text = 'Trial';
            app.TrialEditField = uieditfield(app.UIFigure, 'numeric');
            app.TrialEditField.Editable = 'off';
            app.FrameEditFieldLabel = uilabel(app.UIFigure);
            app.FrameEditFieldLabel.Text = 'Frame';
            app.FrameEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrameEditField.Editable = 'off';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.prestim_text = uilabel(app.UIFigure);
            app.stim_text = uilabel(app.UIFigure);
            app.poststim_text = uilabel(app.UIFigure);
            app.total_text = uilabel(app.UIFigure);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '+';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '+';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '=';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Trial Average')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataAQ

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end